classdef timer_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        YOUWONLabel              matlab.ui.control.Label
        GAMEOVERLabel            matlab.ui.control.Label
        PlacingFlagsButton       matlab.ui.control.StateButton
        DifficultyDropDown       matlab.ui.control.DropDown
        DifficultyDropDownLabel  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        StartButton              matlab.ui.control.Button
        Label                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        stop_clicked = false % is the button pressed or not
        seconds_passed = 0 % how many seconds have passed

        difficulty = 10 % marimea matricei in functie de dificultate
                        % 10 - easy
                        % 15 - medium
                        % 20 - hard
        
        board   %matricea tablei
                
        nr_flags %nr de steaguri care pot fi plasate

        body_width %UIfigure width

        body_height %UIfigure height

        button_array %board body

        button_matrix = [] %matricea de butoane

        label_matrix = [] %matricea de valori

        win_counter %counter for win condition

        flag_icon = imread("Flag_icon.png") %flag icon image

        normal_icon = imread("normal_icon.png") %normal icon image

        board_visited %board of deleted buttons
    end


    methods (Access = private)
        
        function board = create_nxn_board(~,n)
    
            board_vector = randsample([1, 0], n^2,true, [0.2, 0.8]);
            
            board_n = reshape(board_vector, [n, n]);
            
            board = -1 .* board_n;
            
            bomb_count_board = conv2(board_n, ones(3), "same");
            
            board(board ~= -1) = bomb_count_board(board ~= -1);
        
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.body_width = app.UIFigure.Position(3);
            app.body_height = app.UIFigure.Position(4);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                app.PlacingFlagsButton.Enable = "on";

                app.ResetButton.Enable = "on";

                app.StartButton.Enable = "off";

                app.stop_clicked = false;


                app.DifficultyDropDown.Enable = "off";

                %display(app.difficulty);

                app.board = app.create_nxn_board(app.difficulty);

                display(app.board);

                app.board_visited = zeros(app.difficulty);

                app.nr_flags = sum(app.board == -1, "all");

                app.win_counter = app.difficulty^2 - app.nr_flags;

                app.Label_2.Text = num2str(app.nr_flags);

                if app.difficulty == 10
                    app.button_array = uibuttongroup("Parent",app.UIFigure, ...
                        "Units","pixels", ...
                        "Position",[200, 200, 250, 250 ]);
                elseif app.difficulty == 15
                    app.button_array = uibuttongroup("Parent",app.UIFigure, ...
                        "Units","pixels", ...
                        "Position",[137, 137, 375, 375 ]);
                elseif app.difficulty == 20
                    app.button_array = uibuttongroup("Parent",app.UIFigure, ...
                        "Units","pixels", ...
                        "Position",[75, 75, 500, 500 ]);
                end

                %generate the ui for the board
                for i = 0:app.difficulty-1
                    
                    for j = 0:app.difficulty-1
                        x = num2str(app.board(i + 1,j + 1));
                        x = ["<font style = 'text-align:center; size:18; padding-top:0; padding-bottom:8'><b>", x, "</b></font>"];

                        app.label_matrix(i + 1, j + 1) = uilabel(app.button_array, ...
                            "Position",[i * 25 ,j * 25 ,25 ,25], ...
                            "Tag",strcat("Lbl_","i",num2str(i + 1),"j",num2str(j + 1)), ...
                            Interpreter="html",Text=x,HorizontalAlignment='center',VerticalAlignment='center', ...
                            FontSize=14 ...
                            );
                        
                        app.button_matrix(i + 1, j + 1) = uibutton(app.button_array, ...
                            "state","Position",[i * 25 ,j * 25 ,25 ,25], ...
                            "Text","","Tag",strcat("Btt_","i",num2str(i + 1),"j",num2str(j + 1)), ...
                            "ValueChangedFcn",@Matrix_btt_pushed);
                        
                        
                    end

                end

                while ~app.stop_clicked
                        app.Label.Text = num2str(app.seconds_passed);
                        app.seconds_passed = app.seconds_passed + 1;
                        pause(1);


                        drawnow;

                    
                end


            function Matrix_btt_pushed(src,event) 

                display(get(src,"Tag"));
                display(src);
                display(event);

                %%get index of button
                x = get(src,"Tag");

                i = abs(str2double(x(strfind(x , "i") + 1 : strfind(x , "j"))));
                j = str2double(x(strfind(x , "j") + 1 : end));

                display(i);
                display(j);

                if app.PlacingFlagsButton.Value == false


                    %%%%%
                    cascade(i,j);

                    %%game over condition
                    if app.board(i,j) == -1

                        app.StartButton.Enable = "off";
                        app.ResetButtonPushed;
                        app.GAMEOVERLabel.Visible = "on";
                        drawnow;
                        pause(2.5);
                        app.GAMEOVERLabel.Visible = "off";
                        app.StartButton.Enable = "on";

                        drawnow;

                    end

                elseif app.PlacingFlagsButton.Value == true && app.nr_flags ~= 0 && src.Value == true
                    
                    set(src,"icon",app.flag_icon);
                    app.nr_flags = app.nr_flags - 1;
                    app.Label_2.Text = num2str(app.nr_flags);

                elseif app.PlacingFlagsButton.Value == true && src.Value == false

                    set(src,"icon",app.normal_icon);
                    app.nr_flags = app.nr_flags + 1;
                    app.Label_2.Text = num2str(app.nr_flags);

                elseif app.PlacingFlagsButton.Value == true && app.nr_flags == 0 && src.Value == true

                    src.Value = false;

                end

                %controller.change_model(model,i,j,app.PlacingFlagsButton.value)
                
            end

            function cascade(x, y)
    
                if app.board_visited(x, y) == 1
                    return;
                end

                delete_btt(x,y);

                %%win condition
                    if app.win_counter == 0

                        %app.StartButton.Enable = "off";
                        app.ResetButtonPushed;
                        app.YOUWONLabel.Visible = "on";
                        drawnow;
                        pause(2.5);
                        app.YOUWONLabel.Visible = "off";
                        %app.StartButton.Enable = "on";

                        drawnow;

                    end

                display(app.board_visited);

                if app.board(x,y) == 0
                    if x > 1
                        cascade(x - 1, y);
                    end
                    if y > 1
                        cascade(x, y - 1);
                    end
                    if x < app.difficulty
                        cascade(x + 1, y);
                    end
                    if y < app.difficulty
                        cascade(x, y + 1);
                    end

                    if x > 1 && y > 1
                        cascade(x - 1, y - 1);
                    end
                    if x < app.difficulty && y > 1
                        cascade(x + 1, y - 1);
                    end
                    if x < app.difficulty && y < app.difficulty
                        cascade(x + 1, y + 1);
                    end
                    if x > 1 && y < app.difficulty
                        cascade(x - 1, y + 1);
                    end
                end


            end

            function delete_btt (x, y)

                %%delete the button
                    delete(app.button_matrix(x,y));
                    app.win_counter = app.win_counter - 1;
                    app.board_visited(x,y) = 1;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.PlacingFlagsButton.Enable = "off";

            app.ResetButton.Enable = "off";
            
            app.Label_2.Text = "";

            app.seconds_passed = 0;
            
            app.stop_clicked = true;
            
            app.DifficultyDropDown.Enable = "on";

            app.StartButton.Enable = "on";

            delete(app.button_array);
        end

        % Value changed function: DifficultyDropDown
        function DifficultyDropDownValueChanged(app, event)
            app.difficulty = app.DifficultyDropDown.Value;
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if key == 'f'
                app.PlacingFlagsButton.Value = ~app.PlacingFlagsButton.Value;
            end
        end

        % Value changed function: PlacingFlagsButton
        function PlacingFlagsButtonValueChanged(app, event)
            value = app.PlacingFlagsButton.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ResetButtonPushed
            %delete(app.button_array)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.DifficultyDropDownLabel = uilabel(app.UIFigure);
            app.DifficultyDropDownLabel.Text = 'Difficulty';
            app.DifficultyDropDown = uidropdown(app.UIFigure);
            app.DifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.DifficultyDropDown.ItemsData = [10 15 20];
            app.DifficultyDropDown.ValueChangedFcn = createCallbackFcn(app, @DifficultyDropDownValueChanged, true);
            app.DifficultyDropDown.Value = 10;
            app.PlacingFlagsButton = uibutton(app.UIFigure, 'state');
            app.PlacingFlagsButton.ValueChangedFcn = createCallbackFcn(app, @PlacingFlagsButtonValueChanged, true);
            app.PlacingFlagsButton.Enable = 'off';
            app.PlacingFlagsButton.Text = 'Placing Flags';
            app.GAMEOVERLabel = uilabel(app.UIFigure);
            app.GAMEOVERLabel.Visible = 'off';
            app.GAMEOVERLabel.Text = 'GAME OVER';
            app.YOUWONLabel = uilabel(app.UIFigure);
            app.YOUWONLabel.Visible = 'off';
            app.YOUWONLabel.Text = 'YOU WON';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timer_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end